classdef GUI_V1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        AButton    matlab.ui.control.StateButton
        SButton    matlab.ui.control.StateButton
        DButton    matlab.ui.control.StateButton
        WButton    matlab.ui.control.StateButton
        UIAxes     matlab.ui.control.UIAxes
        AButton_2  matlab.ui.control.Button
        SButton_2  matlab.ui.control.Button
        DButton_2  matlab.ui.control.Button
        WButton_2  matlab.ui.control.Button
    end

    
    properties (Access = public)
        x_position = 200            % The starting position of the dot on x axis
        y_position = 101            % The starting position of the dot on y axis
                    
        x_box = [100, 700, 700, 100, 100];  % The outside of the arena
        y_box = [100, 100, 700, 700, 100];  % The outside of the arena
        
        crash = 0;          % Initialize crash as 0
        
        force = 150;        % Force pressed on trigger
        direction = 150;    % Direction of wheels
        speed = 0;          % Speed of car
        facing = pi/2;      % Direction the car is facing in rad 0 = x
        
        s1;
        s2; 
        s3;
        s4;
        s5;
        s6;
        s7;
        s8;
        s9;
        x;
        h;
        t;
        now;  
        Loop;
    end
    
    methods (Access = private)
        
        function collision(app)
            % Checks if the car is outside of the arena
            if (app.x_position >= 700 ) || (app.x_position <= 100) || (app.y_position <= 100) || (app.y_position >= 700)
                app.crash = 1;      % If crashed set crash to 1
                stop(app.Loop);     % If crashed stop the loop
                
                % Plot the crash location
                plot(app.UIAxes, app.x_position, app.y_position,'x','MarkerSize', 40, 'color', 'r', 'LineWidth', 2)
            end
        end
        
        function LoopFcn(app,~,~)
            
            hold(app.UIAxes,'on')   % Keeps old dot locations and arena
           
            collision(app);         % Checks every 0.1 seconds if the car has gone outside the arena

            app.t = toc(app.now);
            app.s1 = sprintf('force: %g N', app.force);         %kitt.force
            app.s2 = sprintf('velocity: m/s');                  %kitt.velocity
            app.s3 = sprintf('angle: %g', app.direction);       %kitt.angle
            app.s4 = sprintf('distL: %g  cm', app.x_position);  %kitt.distL
            app.s5 = sprintf('distR: %g cm', app.y_position);   %kitt.distR
            app.s6 = sprintf('time: %0.2f s',app.t);            %time
            app.s7 = sprintf('crash: %g', app.crash);           %carchrash(kitt);
            app.s8 = sprintf('zaps: ');                         %kitt.numzaps;
            
            delete(app.h);  %delete text before writing it again
            
            app.h(1) = text(app.UIAxes,5,490,app.s1);	
            app.h(2) = text(app.UIAxes,5,470,app.s2);
            app.h(3) = text(app.UIAxes,5,450,app.s3);
            app.h(4) = text(app.UIAxes,5,430,app.s4);
            app.h(5) = text(app.UIAxes,5,410,app.s5);
            app.h(6) = text(app.UIAxes,5,390,app.s6);
            app.h(7) = text(app.UIAxes,5,370,app.s7);
            app.h(8) = text(app.UIAxes,5,350,app.s8);
            
            % Determine new position based on force and angle
            app.facing = app.facing + (app.direction - 150)*pi/180;
            app.x_position = app.x_position + (app.force - 150) * cos(app.facing);
            app.y_position = app.y_position + (app.force - 150) * sin(app.facing);
            
            % If statement to prevent plotting another dot outside the arena
            if (app.crash == 0)
                plot(app.UIAxes, app.x_position, app.y_position,'bo');
            end
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Gives the right axis' on startup
            app.UIAxes.YLim = [0 800];
            app.UIAxes.XLim = [0 800];
            plot(app.UIAxes, app.x_position, app.y_position,'bo', app.x_box, app.y_box, 'r-');
              
            % Prints the initial texts in the plot
            app.now = tic;
            app.s1 = sprintf('force: 0 N');
            app.s2 = sprintf('velocity: 0 m/s');
            app.s3 = sprintf('angle: 0 ');
            app.s4 = sprintf('distL: %g  cm', app.x_position);
            app.s5 = sprintf('distR: %g cm', app.y_position);
            app.s6 = sprintf('time: 0 s');
            app.s9 = sprintf('time: 0 s');
          
            app.h(1) = text(app.UIAxes,5,490,app.s1);	
            app.h(2) = text(app.UIAxes,5,470,app.s2);
            app.h(3) = text(app.UIAxes,5,450,app.s3);
            app.h(4) = text(app.UIAxes,5,430,app.s4);
            app.h(5) = text(app.UIAxes,5,410,app.s5);
            app.h(6) = text(app.UIAxes,5,390,app.s6);
            
            % Create timer object
            app.Loop = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.1, ...                   % Period is 0.1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.LoopFcn);           % Specify callback function
            
            % Start the loop
            start(app.Loop);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
                        
            switch key
                case 'a'
                    if (app.AButton.Value == 1)
                        app.AButton.Value = 0;          %switches state when a pressed
                    else
                        app.AButton.Value = 1;
                        if (app.y_position <= 700 ) && (app.y_position >= 100) && (app.x_position <= 700 ) && (app.x_position >= 100)
                            if (app.direction < 200)
                                % Apply new values depending on button press
                                app.direction = app.direction + 1;
                            end
                        end                        
                    end
                case 'w'
                    if (app.WButton.Value == 1)
                        app.WButton.Value = 0;
                    else
                        app.WButton.Value = 1;
                        if (app.y_position <= 700 ) && (app.y_position >= 100) && (app.x_position <= 700 ) && (app.x_position >= 100)
                            if (app.force < 165)
                                % Apply new values depending on button press
                                app.force = app.force + 1;
                            end    
                        end    
                    end
                case 's'
                    if (app.SButton.Value == 1)
                        app.SButton.Value = 0;
                    else
                        app.SButton.Value = 1;
                        if (app.y_position <= 700 ) && (app.y_position >= 100) && (app.x_position <= 700 ) && (app.x_position >= 100)
                            if (app.force > 135)
                                % Apply new values depending on button press
                                app.force = app.force - 1;
                            end    
                        end
                    end
                case 'd'
                    if (app.DButton.Value == 1)
                        app.DButton.Value = 0;
                    else
                        app.DButton.Value = 1;
                        if (app.y_position <= 700 ) && (app.y_position >= 100) && (app.x_position <= 700 ) && (app.x_position >= 100)
                            if (app.direction > 100)
                                % Apply new values depending on button press
                                app.direction = app.direction - 1;
                            end     
                        end
                    end
                    
%--------------------------------------------------------------------------------%
% Space function is very messy now and not how it should be, needs to be revised %
%--------------------------------------------------------------------------------%

                case 'space'
                    app.UIAxes.cla;
                    app.force = 150;     % reset to initial values
                    app.direction = 150; % reset to initial values
                    app.facing = pi/2;    % reset to initial values
                    %app.x_position = 200; % reset to start
                    %app.y_position = 101; % reset to start
                    
                    plot(app.UIAxes, app.x_position, app.y_position,'bo', app.x_box, app.y_box, 'r-');
                    app.crash = 0;
                    
                    app.now = tic;
                    app.s1 = sprintf('force: 0 N');
                    app.s2 = sprintf('velocity: 0 m/s');
                    app.s3 = sprintf('angle: 0 ');
                    app.s4 = sprintf('distL: %g  cm', app.x_position);
                    app.s5 = sprintf('distR: %g cm', app.y_position);
                    app.s6 = sprintf('time: 0 s');
                    app.s7 = sprintf('crash: %g', app.crash);         %carchrash(kitt);
                    app.s8 = sprintf('zaps: ');          %kitt.numzaps;
                  
                    app.h(1) = text(app.UIAxes,5,490,app.s1);	
                    app.h(2) = text(app.UIAxes,5,470,app.s2);
                    app.h(3) = text(app.UIAxes,5,450,app.s3);
                    app.h(4) = text(app.UIAxes,5,430,app.s4);
                    app.h(5) = text(app.UIAxes,5,410,app.s5);
                    app.h(6) = text(app.UIAxes,5,390,app.s6);
                    app.h(7) = text(app.UIAxes,5,370,app.s7);
                    app.h(8) = text(app.UIAxes,5,350,app.s8);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.AButton = uibutton(app.UIFigure, 'state');
            app.AButton.Text = 'A';
            app.SButton = uibutton(app.UIFigure, 'state');
            app.SButton.Text = 'S';
            app.DButton = uibutton(app.UIFigure, 'state');
            app.DButton.Text = 'D';
            app.WButton = uibutton(app.UIFigure, 'state');
            app.WButton.Text = 'W';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.AButton_2 = uibutton(app.UIFigure, 'push');
            app.AButton_2.Text = 'A';
            app.SButton_2 = uibutton(app.UIFigure, 'push');
            app.SButton_2.Text = 'S';
            app.DButton_2 = uibutton(app.UIFigure, 'push');
            app.DButton_2.Text = 'D';
            app.WButton_2 = uibutton(app.UIFigure, 'push');
            app.WButton_2.Text = 'W';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_V1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end